classdef calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        N1_Button         matlab.ui.control.Button
        N2_Button         matlab.ui.control.Button
        N3_Button         matlab.ui.control.Button
        N4_Button         matlab.ui.control.Button
        N5_Button         matlab.ui.control.Button
        N6_Button         matlab.ui.control.Button
        N7_Button         matlab.ui.control.Button
        N8_Button         matlab.ui.control.Button
        N9_Button         matlab.ui.control.Button
        N0_Button         matlab.ui.control.Button
        P_Button          matlab.ui.control.Button
        D_Button          matlab.ui.control.Button
        M_Button          matlab.ui.control.Button
        S_Button          matlab.ui.control.Button
        A_Button          matlab.ui.control.Button
        calculate_Button  matlab.ui.control.Button
        Clear_Button      matlab.ui.control.Button
        TextArea          matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        prevalue=[]   %前一个值
        value         %当前值
        operator      %运算符
        operator_d     %运算符显示
        output        %结果
        message=cell(2,1) %储存显示信息
        temp=[]     %零时存放显示数据的值
    end
    
    methods (Access = public)
        
        function   Calculate(app)
            if isempty(app.prevalue)||isempty(app.value)
                return
            end
            temp_a=num2str(app.prevalue);
            temp_b=num2str(app.value);
            temp_c=str2sym(strcat(temp_a,app.operator,temp_b));
            app.output=double(temp_c);
        end
    end
    
    methods (Access = private)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.message{1}=num2str(0);
            app.message{2}=num2str(0);
            app.TextArea.Value=app.message;
        end

        % Button pushed function: Clear_Button
        function Clear_ButtonPushed(app, event)
            app.prevalue=[];
            app.value=[];
            app.operator=[];
            app.operator_d=[];
            app.output=[];
            app.temp=[];
            app.message{1}=num2str(0);
            app.message{2}=num2str(0);
            app.TextArea.Value=app.message;
        end

        % Button pushed function: calculate_Button
        function calculate_ButtonPushed(app, event)
            app.value=app.message{2};
            app.Calculate;
            app.message{1}=strcat(app.message{1},app.message{2});
            app.message{2}=num2str(app.output);
            app.message{1}=strcat(app.message{1},'=');
            app.TextArea.Value=app.message;
            app.prevalue=[];
            app.value=app.output;
            app.temp=[];
        end

        % Button pushed function: D_Button
        function D_ButtonPushed(app, event)
            if isempty(app.prevalue)  %前面无结果
                app.prevalue=app.value;
                app.operator='/';
                app.operator_d='÷';
                app.message{1}=strcat(num2str(app.prevalue),app.operator_d);
                app.message{2}=num2str(app.value);
                app.TextArea.Value=app.message;
                app.value=[];
            elseif ~isempty(app.value)
                app.Calculate;
                app.operator='/';
                app.operator_d='÷';
                app.prevalue=app.output;
                app.message{1}=strcat(app.message{1},app.message{2});
                app.message{2}=num2str(app.output);
                app.message{1}=strcat(app.message{1},app.operator_d);
                app.TextArea.Value=app.message;
                app.value=[];
            end
            app.operator='/';
            app.operator_d='÷';
            if isstrprop(app.message{1}(end),'digit')
                app.message{1}=strcat(app.message{1},app.operator_d);
                app.TextArea.Value=app.message;
            else
                app.message{1}(end)=app.operator_d;
                app.TextArea.Value=app.message;
            end
            app.temp=[];
        end

        % Button pushed function: N1_Button
        function N1_ButtonPushed(app, event)
            app.temp=[app.temp,num2str(1)];
            app.message{2}=app.temp;
            app.TextArea.Value=app.message;
            app.value=str2double(app.message{2});
        end

        % Button pushed function: N2_Button
        function N2_ButtonPushed(app, event)
            app.temp=[app.temp,num2str(2)];
            app.message{2}=app.temp;
            app.TextArea.Value=app.message;
            app.value=str2double(app.message{2});
        end

        % Button pushed function: N3_Button
        function N3_ButtonPushed(app, event)
            app.temp=[app.temp,num2str(3)];
            app.message{2}=app.temp;
            app.TextArea.Value=app.message;
            app.value=str2double(app.message{2});
        end

        % Button pushed function: N4_Button
        function N4_ButtonPushed(app, event)
            app.temp=[app.temp,num2str(4)];
            app.message{2}=app.temp;
            app.TextArea.Value=app.message;
            app.value=str2double(app.message{2});
        end

        % Button pushed function: N5_Button
        function N5_ButtonPushed(app, event)
            app.temp=[app.temp,num2str(5)];
            app.message{2}=app.temp;
            app.TextArea.Value=app.message;
            app.value=str2double(app.message{2});
        end

        % Button pushed function: N6_Button
        function N6_ButtonPushed(app, event)
            app.temp=[app.temp,num2str(6)];
            app.message{2}=app.temp;
            app.TextArea.Value=app.message;
            app.value=str2double(app.message{2});
        end

        % Button pushed function: N7_Button
        function N7_ButtonPushed(app, event)
            app.temp=[app.temp,num2str(7)];
            app.message{2}=app.temp;
            app.TextArea.Value=app.message;
            app.value=str2double(app.message{2});
        end

        % Button pushed function: N8_Button
        function N8_ButtonPushed(app, event)
            app.temp=[app.temp,num2str(8)];
            app.message{2}=app.temp;
            app.TextArea.Value=app.message;
            app.value=str2double(app.message{2});
        end

        % Button pushed function: N9_Button
        function N9_ButtonPushed(app, event)
            app.temp=[app.temp,num2str(9)];
            app.message{2}=app.temp;
            app.TextArea.Value=app.message;
            app.value=str2double(app.message{2});
        end

        % Button pushed function: N0_Button
        function N0_ButtonPushed(app, event)
            app.temp=[app.temp,num2str(0)];
            app.message{2}=app.temp;
            app.TextArea.Value=app.message;
            app.value=str2double(app.message{2});
        end

        % Button pushed function: P_Button
        function P_ButtonPushed(app, event)
            app.temp=[app.temp,'.'];
            app.message{2}=app.temp;
            app.TextArea.Value=app.message;
            app.value=str2double(app.message{2});
        end

        % Button pushed function: M_Button
        function M_ButtonPushed(app, event)
            if isempty(app.prevalue)  %前面无结果
                app.prevalue=app.value;
                app.operator='*';
                app.operator_d='×';
                app.message{1}=strcat(num2str(app.prevalue),app.operator_d);
                app.message{2}=num2str(app.value);
                app.TextArea.Value=app.message;
                app.value=[];
            elseif ~isempty(app.value)
                app.Calculate;
                app.operator='*';
                app.operator_d='×';
                app.prevalue=app.output;
                app.message{1}=strcat(app.message{1},app.message{2});
                app.message{2}=num2str(app.output);
                app.message{1}=strcat(app.message{1},app.operator_d);
                app.TextArea.Value=app.message;
                app.value=[];
            end
            app.operator='*';
            app.operator_d='×';
            if isstrprop(app.message{1}(end),'digit')
                app.message{1}=strcat(app.message{1},app.operator_d);
                app.TextArea.Value=app.message;
            else
                app.message{1}(end)=app.operator_d;
                app.TextArea.Value=app.message;
            end
            app.temp=[];
        end

        % Button pushed function: S_Button
        function S_ButtonPushed(app, event)
            if isempty(app.prevalue)  %前面无结果
                app.prevalue=app.value;
                app.operator='-';
                app.operator_d='-';
                app.message{1}=strcat(num2str(app.prevalue),app.operator_d);
                app.message{2}=num2str(app.value);
                app.TextArea.Value=app.message;
                app.value=[];
            elseif ~isempty(app.value)
                app.Calculate;
                app.operator='-';
                app.operator_d='-';
                app.prevalue=app.output;
                app.message{1}=strcat(app.message{1},app.message{2});
                app.message{2}=num2str(app.output);
                app.message{1}=strcat(app.message{1},app.operator_d);
                app.TextArea.Value=app.message;
                app.value=[];
            end
            app.operator='-';
            app.operator_d='-';
            if isstrprop(app.message{1}(end),'digit')
                app.message{1}=strcat(app.message{1},app.operator_d);
                app.TextArea.Value=app.message;
            else
                app.message{1}(end)=app.operator_d;
                app.TextArea.Value=app.message;
            end
            app.temp=[];
        end

        % Button pushed function: A_Button
        function A_ButtonPushed(app, event)
            if isempty(app.prevalue)  %前面无结果
                app.prevalue=app.value;
                app.operator='+';
                app.operator_d='+';
                app.message{1}=strcat(num2str(app.prevalue),app.operator_d);
                app.message{2}=num2str(app.value);
                app.TextArea.Value=app.message;
                app.value=[];
            elseif ~isempty(app.value)
                app.Calculate;
                app.operator='+';
                app.operator_d='+';
                app.prevalue=app.output;
                app.message{1}=strcat(app.message{1},app.message{2});
                app.message{2}=num2str(app.output);
                app.message{1}=strcat(app.message{1},app.operator_d);
                app.TextArea.Value=app.message;
                app.value=[];
            end
            app.operator='+';
            app.operator_d='+';
            if isstrprop(app.message{1}(end),'digit')
                app.message{1}=strcat(app.message{1},app.operator_d);
                app.TextArea.Value=app.message;
            else
                app.message{1}(end)=app.operator_d;
                app.TextArea.Value=app.message;
            end
            app.temp=[];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.N1_Button = uibutton(app.UIFigure, 'push');
            app.N1_Button.ButtonPushedFcn = createCallbackFcn(app, @N1_ButtonPushed, true);
            app.N1_Button.Text = '1';
            app.N2_Button = uibutton(app.UIFigure, 'push');
            app.N2_Button.ButtonPushedFcn = createCallbackFcn(app, @N2_ButtonPushed, true);
            app.N2_Button.Text = '2';
            app.N3_Button = uibutton(app.UIFigure, 'push');
            app.N3_Button.ButtonPushedFcn = createCallbackFcn(app, @N3_ButtonPushed, true);
            app.N3_Button.Text = '3';
            app.N4_Button = uibutton(app.UIFigure, 'push');
            app.N4_Button.ButtonPushedFcn = createCallbackFcn(app, @N4_ButtonPushed, true);
            app.N4_Button.Text = '4';
            app.N5_Button = uibutton(app.UIFigure, 'push');
            app.N5_Button.ButtonPushedFcn = createCallbackFcn(app, @N5_ButtonPushed, true);
            app.N5_Button.Text = '5';
            app.N6_Button = uibutton(app.UIFigure, 'push');
            app.N6_Button.ButtonPushedFcn = createCallbackFcn(app, @N6_ButtonPushed, true);
            app.N6_Button.Text = '6';
            app.N7_Button = uibutton(app.UIFigure, 'push');
            app.N7_Button.ButtonPushedFcn = createCallbackFcn(app, @N7_ButtonPushed, true);
            app.N7_Button.Text = '7';
            app.N8_Button = uibutton(app.UIFigure, 'push');
            app.N8_Button.ButtonPushedFcn = createCallbackFcn(app, @N8_ButtonPushed, true);
            app.N8_Button.Text = '8';
            app.N9_Button = uibutton(app.UIFigure, 'push');
            app.N9_Button.ButtonPushedFcn = createCallbackFcn(app, @N9_ButtonPushed, true);
            app.N9_Button.Text = '9';
            app.N0_Button = uibutton(app.UIFigure, 'push');
            app.N0_Button.ButtonPushedFcn = createCallbackFcn(app, @N0_ButtonPushed, true);
            app.N0_Button.Text = '0';
            app.P_Button = uibutton(app.UIFigure, 'push');
            app.P_Button.ButtonPushedFcn = createCallbackFcn(app, @P_ButtonPushed, true);
            app.P_Button.Text = '.';
            app.D_Button = uibutton(app.UIFigure, 'push');
            app.D_Button.ButtonPushedFcn = createCallbackFcn(app, @D_ButtonPushed, true);
            app.D_Button.Text = '÷';
            app.M_Button = uibutton(app.UIFigure, 'push');
            app.M_Button.ButtonPushedFcn = createCallbackFcn(app, @M_ButtonPushed, true);
            app.M_Button.Text = '×';
            app.S_Button = uibutton(app.UIFigure, 'push');
            app.S_Button.ButtonPushedFcn = createCallbackFcn(app, @S_ButtonPushed, true);
            app.S_Button.Text = '-';
            app.A_Button = uibutton(app.UIFigure, 'push');
            app.A_Button.ButtonPushedFcn = createCallbackFcn(app, @A_ButtonPushed, true);
            app.A_Button.Text = '+';
            app.calculate_Button = uibutton(app.UIFigure, 'push');
            app.calculate_Button.ButtonPushedFcn = createCallbackFcn(app, @calculate_ButtonPushed, true);
            app.calculate_Button.Text = '=';
            app.Clear_Button = uibutton(app.UIFigure, 'push');
            app.Clear_Button.ButtonPushedFcn = createCallbackFcn(app, @Clear_ButtonPushed, true);
            app.Clear_Button.Text = 'C';
            app.TextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = calculator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end